classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        EditField_2              matlab.ui.control.NumericEditField
        EditField_2Label         matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        EditFieldLabel           matlab.ui.control.Label
        GOButton                 matlab.ui.control.Button
        DropDown                 matlab.ui.control.DropDown
        DropDownLabel            matlab.ui.control.Label
        LoadInputImageButton     matlab.ui.control.Button
        RedAxes                  matlab.ui.control.UIAxes
        ImageAxes                matlab.ui.control.UIAxes
        CenterPanel              matlab.ui.container.Panel
        LoadSpecificImageButton  matlab.ui.control.Button
        RedAxes_4                matlab.ui.control.UIAxes
        ImageAxes_4              matlab.ui.control.UIAxes
        RightPanel               matlab.ui.container.Panel
        RedAxes_3                matlab.ui.control.UIAxes
        ImageAxes_3              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CenterPanel.Visible = 'off';
            app.RightPanel.Visible ='off';
            app.EditField.Visible ='off';
            app.EditField_2.Visible ='off';
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if strcmp(value, "x")
                app.RightPanel.Visible = 'off';
                app.EditField_2.Visible ='off';
                app.CenterPanel.Visible = 'on';
                app.EditField.Visible ='on';
            elseif strcmp(value, 'y')
                app.CenterPanel.Visible = 'off';
                app.EditField.Visible ='off';
                app.RightPanel.Visible = 'on';
                app.EditField_2.Visible ='on';
            end
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {737, 737, 737};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {737, 737};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {388, '1x', 338};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImageAxes = uiaxes(app.LeftPanel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.RedAxes = uiaxes(app.LeftPanel);
            title(app.RedAxes, 'Red')
            xlabel(app.RedAxes, 'Intensity')
            ylabel(app.RedAxes, 'Pixels')
            app.RedAxes.XLim = [0 255];
            app.RedAxes.XTick = [0 128 255];
            app.LoadInputImageButton = uibutton(app.LeftPanel, 'push');
            app.LoadInputImageButton.Text = 'Load Input Image';
            app.DropDownLabel = uilabel(app.LeftPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'x', 'y'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'x';
            app.GOButton = uibutton(app.LeftPanel, 'push');
            app.GOButton.Text = 'GO!';
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_2Label = uilabel(app.LeftPanel);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.CenterPanel = uipanel(app.GridLayout);
            app.ImageAxes_4 = uiaxes(app.CenterPanel);
            app.ImageAxes_4.XTick = [];
            app.ImageAxes_4.XTickLabel = {'[ ]'};
            app.ImageAxes_4.YTick = [];
            app.RedAxes_4 = uiaxes(app.CenterPanel);
            title(app.RedAxes_4, 'Red')
            xlabel(app.RedAxes_4, 'Intensity')
            ylabel(app.RedAxes_4, 'Pixels')
            app.RedAxes_4.XLim = [0 255];
            app.RedAxes_4.XTick = [0 128 255];
            app.LoadSpecificImageButton = uibutton(app.CenterPanel, 'push');
            app.LoadSpecificImageButton.Text = 'Load  Specific Image';
            app.RightPanel = uipanel(app.GridLayout);
            app.ImageAxes_3 = uiaxes(app.RightPanel);
            app.ImageAxes_3.XTick = [];
            app.ImageAxes_3.XTickLabel = {'[ ]'};
            app.ImageAxes_3.YTick = [];
            app.RedAxes_3 = uiaxes(app.RightPanel);
            title(app.RedAxes_3, 'Red')
            xlabel(app.RedAxes_3, 'Intensity')
            ylabel(app.RedAxes_3, 'Pixels')
            app.RedAxes_3.XLim = [0 255];
            app.RedAxes_3.XTick = [0 128 255];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end