classdef app1_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Menu                  matlab.ui.container.Menu
        RightPanel            matlab.ui.container.Panel
        ReferenceLabel        matlab.ui.control.Label
        LoadImageButton       matlab.ui.control.Button
        RedAxes_11            matlab.ui.control.UIAxes
        RedAxes_10            matlab.ui.control.UIAxes
        RedAxes_9             matlab.ui.control.UIAxes
        ImageAxes_5           matlab.ui.control.UIAxes
        CenterPanel           matlab.ui.container.Panel
        OutputLabel           matlab.ui.control.Label
        RedAxes_5             matlab.ui.control.UIAxes
        RedAxes_4             matlab.ui.control.UIAxes
        RedAxes_2             matlab.ui.control.UIAxes
        ImageAxes_4           matlab.ui.control.UIAxes
        LeftPanel             matlab.ui.container.Panel
        InputLabel            matlab.ui.control.Label
        bEditField            matlab.ui.control.NumericEditField
        bEditFieldLabel       matlab.ui.control.Label
        aEditField            matlab.ui.control.NumericEditField
        aEditFieldLabel       matlab.ui.control.Label
        GOButton              matlab.ui.control.Button
        DropDown              matlab.ui.control.DropDown
        DropDownLabel         matlab.ui.control.Label
        LoadInputImageButton  matlab.ui.control.Button
        input_blue            matlab.ui.control.UIAxes
        input_green           matlab.ui.control.UIAxes
        input_red             matlab.ui.control.UIAxes
        ImageAxes             matlab.ui.control.UIAxes
    end

    % Methods that control component visibility
    methods (Access = private) 
        
        function histogramSpecs(app)
            app.CenterPanel.Enable = 'off';
            app.RightPanel.Enable = 'off';

            app.aEditField.Visible ='off';
            app.bEditField.Visible ='off';
            
            app.aEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Visible = 'off';
        end

        function inputSpecs(app, input1, input2)
            app.CenterPanel.Enable = 'on';
            app.RightPanel.Enable = 'off';

            app.aEditField.Visible ='on';
            app.bEditField.Visible ='on';
            
            app.aEditFieldLabel.Visible = 'on';
            app.bEditFieldLabel.Visible = 'on';

            app.aEditFieldLabel.Text = input1;
            app.bEditFieldLabel.Text = input2;
        end

        function noInputSpecs(app)
            app.CenterPanel.Enable = 'on';
            app.RightPanel.Enable = 'off';

            app.aEditField.Visible ='off';
            app.bEditField.Visible ='off';
            
            app.aEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Visible = 'off';
        end

        function histogramSpecSpecs(app)
            app.CenterPanel.Enable = 'on';
            app.RightPanel.Enable = 'on';

            app.aEditField.Visible ='off';
            app.bEditField.Visible ='off';
            
            app.aEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Visible = 'off';
        end
    end
    
    %Show Histogram Method
    methods (Access = public)
        
        function updateImage(app,imagefile)
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure,ME.message,"Image Error");
                    return;            
                end 
            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imshow(im, 'Parent', app.ImageAxes,'XData', [1 app.ImageAxes.Position(3)],'YData', [1 app.ImageAxes.Position(4)]);
                    [~, pixelCount] = show_histogram(im);
                    
                    
                    app.input_red.Visible = "off";
                    app.input_blue.Visible = "off";
                    
                    app.input_green.Title = "Grey Level";

                    bar(app.input_green, pixelCount)
                case 3
                    % Display the truecolor image
                    imshow(im, 'Parent', app.ImageAxes,'XData', [1 app.ImageAxes.Position(3)],'YData', [1 app.ImageAxes.Position(4)]);
                    [~, pixelCount] = show_histogram(im);

                    app.input_red.Visible = "on";
                    app.input_blue.Visible = "on";
                    % Plot the histograms
                    bar(app.input_red,pixelCount(:, 1), 'r')
                    bar(app.input_green,pixelCount(:, 2), 'g')
                    bar(app.input_blue,pixelCount(:, 3), 'b')
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure,"Image must be grayscale or truecolor.","Image Error");
                    return;
                    
            end     
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CenterPanel.Enable = 'off';
            app.RightPanel.Enable = 'off';

            app.aEditField.Visible ='off';
            app.bEditField.Visible ='off';
            
            app.aEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Visible = 'off';
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case 'Show Histogram'
                    histogramSpecs(app);
                case 'Image Brightening'
                    inputSpecs(app, 'a', 'b')
                case 'Pos to Neg'
                    noInputSpecs(app)
                case 'Neg to Pos'
                    noInputSpecs(app)
                case 'Log Transform'
                    inputSpecs(app, 'c', 'r')
                case 'Power Transform'
                   inputSpecs(app, 'c', 'y')
                case 'Contrast Stretching'
                   noInputSpecs(app)
                case 'Histogram Eq'
                   noInputSpecs(app)
                case 'Histogram Spec'
                    histogramSpecSpecs(app)
                otherwise
                
            end
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            
        end

        % Button pushed function: LoadInputImageButton
        function LoadInputImageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [file, path] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(path))
               fname = [path file];
               updateImage(app,fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.LeftPanel = uipanel(app.UIFigure);
            app.ImageAxes = uiaxes(app.LeftPanel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.input_red = uiaxes(app.LeftPanel);
            title(app.input_red, 'Red')
            xlabel(app.input_red, 'Grey Level')
            ylabel(app.input_red, 'Frequency')
            app.input_red.XLim = [0 255];
            app.input_red.XTick = [0 128 255];
            app.input_green = uiaxes(app.LeftPanel);
            title(app.input_green, 'Green')
            xlabel(app.input_green, 'Grey Level')
            ylabel(app.input_green, 'Frequency')
            app.input_green.XLim = [0 255];
            app.input_green.XTick = [0 128 255];
            app.input_blue = uiaxes(app.LeftPanel);
            title(app.input_blue, 'Blue')
            xlabel(app.input_blue, 'Grey Level')
            ylabel(app.input_blue, 'Frequency')
            app.input_blue.XLim = [0 255];
            app.input_blue.XTick = [0 128 255];
            app.LoadInputImageButton = uibutton(app.LeftPanel, 'push');
            app.LoadInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInputImageButtonPushed, true);
            app.LoadInputImageButton.Text = 'Load Input Image';
            app.DropDownLabel = uilabel(app.LeftPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'Show Histogram', 'Image Brightening', 'Pos to Neg', 'Neg to Pos', 'Log Transform', 'Power Transform', 'Contrast Stretching', 'Histogram Eq', 'Histogram Spec'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Placeholder = 'Choose Function';
            app.DropDown.Value = 'Show Histogram';
            app.GOButton = uibutton(app.LeftPanel, 'push');
            app.GOButton.Text = 'GO!';
            app.aEditFieldLabel = uilabel(app.LeftPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.LeftPanel, 'numeric');
            app.bEditFieldLabel = uilabel(app.LeftPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InputLabel = uilabel(app.LeftPanel);
            app.InputLabel.Text = 'Input';
            app.CenterPanel = uipanel(app.UIFigure);
            app.ImageAxes_4 = uiaxes(app.CenterPanel);
            app.ImageAxes_4.XTick = [];
            app.ImageAxes_4.XTickLabel = {'[ ]'};
            app.ImageAxes_4.YTick = [];
            app.RedAxes_2 = uiaxes(app.CenterPanel);
            title(app.RedAxes_2, 'Red')
            xlabel(app.RedAxes_2, 'Grey Level')
            ylabel(app.RedAxes_2, 'Frequency')
            app.RedAxes_2.XLim = [0 255];
            app.RedAxes_2.XTick = [0 128 255];
            app.RedAxes_4 = uiaxes(app.CenterPanel);
            title(app.RedAxes_4, 'Red')
            xlabel(app.RedAxes_4, 'Grey Level')
            ylabel(app.RedAxes_4, 'Frequency')
            app.RedAxes_4.XLim = [0 255];
            app.RedAxes_4.XTick = [0 128 255];
            app.RedAxes_5 = uiaxes(app.CenterPanel);
            title(app.RedAxes_5, 'Red')
            xlabel(app.RedAxes_5, 'Grey Level')
            ylabel(app.RedAxes_5, 'Frequency')
            app.RedAxes_5.XLim = [0 255];
            app.RedAxes_5.XTick = [0 128 255];
            app.OutputLabel = uilabel(app.CenterPanel);
            app.OutputLabel.Text = 'Output';
            app.RightPanel = uipanel(app.UIFigure);
            app.ImageAxes_5 = uiaxes(app.RightPanel);
            app.ImageAxes_5.XTick = [];
            app.ImageAxes_5.XTickLabel = {'[ ]'};
            app.ImageAxes_5.YTick = [];
            app.RedAxes_9 = uiaxes(app.RightPanel);
            title(app.RedAxes_9, 'Red')
            xlabel(app.RedAxes_9, 'Grey Level')
            ylabel(app.RedAxes_9, 'Frequency')
            app.RedAxes_9.XLim = [0 255];
            app.RedAxes_9.XTick = [0 128 255];
            app.RedAxes_10 = uiaxes(app.RightPanel);
            title(app.RedAxes_10, 'Red')
            xlabel(app.RedAxes_10, 'Grey Level')
            ylabel(app.RedAxes_10, 'Frequency')
            app.RedAxes_10.XLim = [0 255];
            app.RedAxes_10.XTick = [0 128 255];
            app.RedAxes_11 = uiaxes(app.RightPanel);
            title(app.RedAxes_11, 'Red')
            xlabel(app.RedAxes_11, 'Grey Level')
            ylabel(app.RedAxes_11, 'Frequency')
            app.RedAxes_11.XLim = [0 255];
            app.RedAxes_11.XTick = [0 128 255];
            app.LoadImageButton = uibutton(app.RightPanel, 'push');
            app.LoadImageButton.Text = 'Load Image';
            app.ReferenceLabel = uilabel(app.RightPanel);
            app.ReferenceLabel.Text = 'Reference';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end