classdef main_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Menu                  matlab.ui.container.Menu
        RightPanel            matlab.ui.container.Panel
        ReferenceLabel        matlab.ui.control.Label
        LoadImageButton       matlab.ui.control.Button
        reference_blue        matlab.ui.control.UIAxes
        reference_green       matlab.ui.control.UIAxes
        reference_red         matlab.ui.control.UIAxes
        ImageReference        matlab.ui.control.UIAxes
        CenterPanel           matlab.ui.container.Panel
        DownloadButton        matlab.ui.control.Button
        OutputLabel           matlab.ui.control.Label
        output_blue           matlab.ui.control.UIAxes
        output_green          matlab.ui.control.UIAxes
        output_red            matlab.ui.control.UIAxes
        ImageOutput           matlab.ui.control.UIAxes
        LeftPanel             matlab.ui.container.Panel
        InputLabel            matlab.ui.control.Label
        EditField_2           matlab.ui.control.NumericEditField
        bEditFieldLabel       matlab.ui.control.Label
        EditField_1           matlab.ui.control.NumericEditField
        aEditFieldLabel       matlab.ui.control.Label
        GOButton              matlab.ui.control.Button
        DropDown              matlab.ui.control.DropDown
        DropDownLabel         matlab.ui.control.Label
        LoadInputImageButton  matlab.ui.control.Button
        ImageInput            matlab.ui.control.UIAxes
        input_blue            matlab.ui.control.UIAxes
        input_green           matlab.ui.control.UIAxes
        input_red             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        InputImage % Input Image
        ReferenceImage % Reference Image
        OutputImage % Output Image
    end

    % Methods that control component visibility
    methods (Access = private) 
        
        function histogramSpecs(app)
            app.CenterPanel.Enable = 'off';
            app.RightPanel.Enable = 'off';

            app.EditField_1.Visible ='off';
            app.EditField_2.Visible ='off';
            
            app.aEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Visible = 'off';
        end
        
        function oneInputSpecs(app, input1)
            app.CenterPanel.Enable = 'on';
            app.RightPanel.Enable = 'off';

            app.EditField_1.Visible ='on';
            app.EditField_2.Visible ='off';
            
            app.aEditFieldLabel.Visible = 'on';
            app.bEditFieldLabel.Visible = 'off';

            app.aEditFieldLabel.Text = input1;
        end
            
        function twoInputSpecs(app, input1, input2)
            app.CenterPanel.Enable = 'on';
            app.RightPanel.Enable = 'off';

            app.EditField_1.Visible ='on';
            app.EditField_2.Visible ='on';
            
            app.aEditFieldLabel.Visible = 'on';
            app.bEditFieldLabel.Visible = 'on';

            app.aEditFieldLabel.Text = input1;
            app.bEditFieldLabel.Text = input2;
        end

        function noInputSpecs(app)
            app.CenterPanel.Enable = 'on';
            app.RightPanel.Enable = 'off';

            app.EditField_1.Visible ='off';
            app.EditField_2.Visible ='off';
            
            app.aEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Visible = 'off';
        end

        function histogramSpecSpecs(app)
            app.CenterPanel.Enable = 'on';
            app.RightPanel.Enable = 'on';

            app.EditField_1.Visible ='off';
            app.EditField_2.Visible ='off';
            
            app.aEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Visible = 'off';
        end
    end
    
    %Image Methods
    methods (Access = public)

        %Clear Histogram
        function clearHist(~, histogramR, histogramG, histogramB)
            cla(histogramR)
            cla(histogramG)
            cla(histogramB)
        end

        %Function to show image
        function showImage(app, image, imageAxes, histogramR, histogramG, histogramB)
            switch size(image,3)
                case 1
                    % Display the grayscale image
                    imshow(image, 'Parent', imageAxes,'XData', [1 imageAxes.Position(3)],'YData', [1 imageAxes.Position(4)]);
                    [~, pixelCount] = show_histogram(image);
                    clearHist(app, histogramR, histogramG, histogramB)
                    
                    histogramR.Title.String = "Grey Level";

                    bar(histogramR, pixelCount, "black")

                    histogramG.Visible = "off";
                    histogramB.Visible = "off";
                case 3
                    % Display the truecolor image
                    imshow(image, 'Parent', imageAxes,'XData', [1 imageAxes.Position(3)],'YData', [1 imageAxes.Position(4)]);
                    [~, pixelCount] = show_histogram(image);

                    clearHist(app, histogramR, histogramG, histogramB)

                    histogramR.Visible = "on";
                    histogramG.Visible = "on";
                    histogramB.Visible = "on";
                    % Plot the histograms
                    bar(histogramR,pixelCount(:, 1), 'r')
                    bar(histogramG,pixelCount(:, 2), 'g')
                    bar(histogramB,pixelCount(:, 3), 'b')
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure,"Image must be grayscale or truecolor.","Image Error");
                    return;
            end
        end

        %Function to updateImage
        function updateImage(app, im, imageAxes, histogramR, histogramG, histogramB)
            % Create histograms based on number of color channels
            showImage(app, im, imageAxes, histogramR, histogramG, histogramB)
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CenterPanel.Enable = 'off';
            app.RightPanel.Enable = 'off';

            app.EditField_1.Visible ='off';
            app.EditField_2.Visible ='off';
            
            app.aEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Visible = 'off';
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case 'Show Histogram'
                    histogramSpecs(app);
                case 'Image Brightening'
                    twoInputSpecs(app, 'a', 'b')
                case 'Pos to Neg'
                    noInputSpecs(app)
                case 'Neg to Pos'
                    noInputSpecs(app)
                case 'Log Transform'
                    oneInputSpecs(app, 'c')
                case 'Power Transform'
                    twoInputSpecs(app, 'c', 'y')
                case 'Contrast Stretching'
                    noInputSpecs(app)
                case 'Histogram Eq'
                    noInputSpecs(app)
                case 'Histogram Spec'
                    histogramSpecSpecs(app)
                otherwise
                
            end
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            
        end

        % Button pushed function: LoadInputImageButton
        function LoadInputImageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.bmp','All Image Files'};
            [file, path] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(path))
               fname = [path file];
               try
                   im = imread(fname);
               catch ME
                   % If problem reading image, display error message
                   uialert(app.UIFigure,ME.message,"Image Error");
                   return;            
               end 
               app.InputImage = im;
               cla(app.input_red);
               cla(app.input_green);
               cla(app.input_blue);
               updateImage(app,im, app.ImageInput, app.input_red, app.input_green, app.input_blue);
            end
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.bmp;*.jpeg;*.jfif;*.webp;','All Image Files'};
            [file, path] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(path))
               fname = [path file];
               try
                   im = imread(fname);
               catch ME
                   % If problem reading image, display error message
                   uialert(app.UIFigure,ME.message,"Image Error");
                   return;            
               end 
               app.ReferenceImage = im;
               cla(app.reference_red);
               cla(app.reference_green);
               cla(app.reference_blue);
               updateImage(app,im, app.ImageReference, app.reference_red, app.reference_green, app.reference_blue);
            end
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            dropdownValue = app.DropDown.Value;

            %check if user input 
            if isempty(app.InputImage)
                uialert(app.UIFigure, "Input image is empty. Load an image first.", "Image Error");
                return;
            end

            outputImage = [];

            if(strcmp(dropdownValue, "Image Brightening"))
                if isempty(app.EditField_1.Value) || isempty(app.EditField_2.Value)
                    uialert(app.UIFigure, "Please provide values for 'a' and 'b'.", "Input Error");
                    return;
                end
                a = app.EditField_1.Value;
                b = app.EditField_2.Value;

                outputImage = image_brightening(app.InputImage, a, b);
                showImage(app, outputImage, app.ImageOutput, app.output_red, app.output_green, app.output_blue)
            elseif(strcmp(dropdownValue, "Pos to Neg") || strcmp(dropdownValue, "Neg to Pos"))
                outputImage = pos_to_neg(app.InputImage);
                showImage(app, outputImage, app.ImageOutput, app.output_red, app.output_green, app.output_blue)
            elseif(strcmp(dropdownValue, "Log Transform"))
                
                if isempty(app.EditField_1.Value)
                    uialert(app.UIFigure, "Please provide values for 'c' .", "Input Error");
                    return;
                end

                c = app.EditField_1.Value;

                outputImage = log_transformation(app.InputImage,c);
                showImage(app, outputImage, app.ImageOutput, app.output_red, app.output_green, app.output_blue)
            elseif(strcmp(dropdownValue, "Power Transform"))

                if isempty(app.EditField_1.Value)
                    uialert(app.UIFigure, "Please provide values for 'c' and 'gamma'.", "Input Error");
                    return;
                end
                
                c = app.EditField_1.Value;
                gamma = app.EditField_2.Value;

                outputImage = power_transformation(app.InputImage,c, gamma);
                showImage(app, outputImage, app.ImageOutput, app.output_red, app.output_green, app.output_blue)
            elseif(strcmp(dropdownValue, "Contrast Stretching"))
                outputImage = contrast_stretching(app.InputImage);
                showImage(app, outputImage, app.ImageOutput, app.output_red, app.output_green, app.output_blue)
            elseif(strcmp(dropdownValue, "Histogram Eq"))
                outputImage = histogram_eq(app.InputImage);
                showImage(app, outputImage, app.ImageOutput, app.output_red, app.output_green, app.output_blue)
            elseif(strcmp(dropdownValue, "Histogram Spec"))
                if ~isequal(size(app.InputImage), size(app.ReferenceImage))
                    uialert(app.UIFigure, "Image size is not equal", "Image Error");
                    return;
                end
                outputImage = histogram_spec(app.InputImage, app.ReferenceImage);
                showImage(app, outputImage, app.ImageOutput, app.output_red, app.output_green, app.output_blue)
                showImage(app, app.ReferenceImage, app.ImageReference, app.reference_red, app.reference_green, app.reference_blue)
            end

            if ~isempty(outputImage)
                app.OutputImage = outputImage;
            end
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
            if isempty(app.OutputImage)
                uialert(app.UIFigure, "No output image available to download.", "Error");
                return;
            end
        
            [filename, filepath] = uiputfile({'*.png', 'PNG Image (*.png)'; '*.jpg', 'JPEG Image (*.jpg)'; '*.bmp', 'Bitmap Image (*.bmp)'; '*.tiff', 'TIFF Image (*.tiff)'; '*.*', 'All Files (*.*)'}, 'Save Image As');
        
            if isequal(filename, 0) || isequal(filepath, 0)
                return;
            end
        
            fullpath = fullfile(filepath, filename);
        
            try
                imwrite(app.OutputImage, fullpath);
                uialert(app.UIFigure, "Image saved successfully.", "Success", "Icon","success");
            catch
                uialert(app.UIFigure, "Failed to save the image.", "Error");
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.LeftPanel = uipanel(app.UIFigure);
            app.input_red = uiaxes(app.LeftPanel);
            title(app.input_red, 'Red')
            xlabel(app.input_red, 'Grey Level')
            ylabel(app.input_red, 'Frequency')
            app.input_red.XLim = [0 255];
            app.input_red.XTick = [0 128 255];
            app.input_green = uiaxes(app.LeftPanel);
            title(app.input_green, 'Green')
            xlabel(app.input_green, 'Grey Level')
            ylabel(app.input_green, 'Frequency')
            app.input_green.XLim = [0 255];
            app.input_green.XTick = [0 128 255];
            app.input_blue = uiaxes(app.LeftPanel);
            title(app.input_blue, 'Blue')
            xlabel(app.input_blue, 'Grey Level')
            ylabel(app.input_blue, 'Frequency')
            app.input_blue.XLim = [0 255];
            app.input_blue.XTick = [0 128 255];
            app.ImageInput = uiaxes(app.LeftPanel);
            app.ImageInput.XTick = [];
            app.ImageInput.XTickLabel = {'[ ]'};
            app.ImageInput.YTick = [];
            app.LoadInputImageButton = uibutton(app.LeftPanel, 'push');
            app.LoadInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInputImageButtonPushed, true);
            app.LoadInputImageButton.Text = 'Load Input Image';
            app.DropDownLabel = uilabel(app.LeftPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'Show Histogram', 'Image Brightening', 'Pos to Neg', 'Neg to Pos', 'Log Transform', 'Power Transform', 'Contrast Stretching', 'Histogram Eq', 'Histogram Spec'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Placeholder = 'Choose Function';
            app.DropDown.Value = 'Show Histogram';
            app.GOButton = uibutton(app.LeftPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO!';
            app.aEditFieldLabel = uilabel(app.LeftPanel);
            app.aEditFieldLabel.Text = 'a';
            app.EditField_1 = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_1.Value = 1;
            app.bEditFieldLabel = uilabel(app.LeftPanel);
            app.bEditFieldLabel.Text = 'b';
            app.EditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_2.Value = 1;
            app.InputLabel = uilabel(app.LeftPanel);
            app.InputLabel.Text = 'Input';
            app.CenterPanel = uipanel(app.UIFigure);
            app.ImageOutput = uiaxes(app.CenterPanel);
            app.ImageOutput.XTick = [];
            app.ImageOutput.XTickLabel = {'[ ]'};
            app.ImageOutput.YTick = [];
            app.output_red = uiaxes(app.CenterPanel);
            title(app.output_red, 'Red')
            xlabel(app.output_red, 'Grey Level')
            ylabel(app.output_red, 'Frequency')
            app.output_red.XLim = [0 255];
            app.output_red.XTick = [0 128 255];
            app.output_green = uiaxes(app.CenterPanel);
            title(app.output_green, 'Green')
            xlabel(app.output_green, 'Grey Level')
            ylabel(app.output_green, 'Frequency')
            app.output_green.XLim = [0 255];
            app.output_green.XTick = [0 128 255];
            app.output_blue = uiaxes(app.CenterPanel);
            title(app.output_blue, 'Blue')
            xlabel(app.output_blue, 'Grey Level')
            ylabel(app.output_blue, 'Frequency')
            app.output_blue.XLim = [0 255];
            app.output_blue.XTick = [0 128 255];
            app.OutputLabel = uilabel(app.CenterPanel);
            app.OutputLabel.Text = 'Output';
            app.DownloadButton = uibutton(app.CenterPanel, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Text = 'Download';
            app.RightPanel = uipanel(app.UIFigure);
            app.ImageReference = uiaxes(app.RightPanel);
            app.ImageReference.XTick = [];
            app.ImageReference.XTickLabel = {'[ ]'};
            app.ImageReference.YTick = [];
            app.reference_red = uiaxes(app.RightPanel);
            title(app.reference_red, 'Red')
            xlabel(app.reference_red, 'Grey Level')
            ylabel(app.reference_red, 'Frequency')
            app.reference_red.XLim = [0 255];
            app.reference_red.XTick = [0 128 255];
            app.reference_green = uiaxes(app.RightPanel);
            title(app.reference_green, 'Green')
            xlabel(app.reference_green, 'Grey Level')
            ylabel(app.reference_green, 'Frequency')
            app.reference_green.XLim = [0 255];
            app.reference_green.XTick = [0 128 255];
            app.reference_blue = uiaxes(app.RightPanel);
            title(app.reference_blue, 'Blue')
            xlabel(app.reference_blue, 'Grey Level')
            ylabel(app.reference_blue, 'Frequency')
            app.reference_blue.XLim = [0 255];
            app.reference_blue.XTick = [0 128 255];
            app.LoadImageButton = uibutton(app.RightPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ReferenceLabel = uilabel(app.RightPanel);
            app.ReferenceLabel.Text = 'Reference';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end