classdef tset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Menu                  matlab.ui.container.Menu
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        LeftPanel2            matlab.ui.container.Panel
        InputLabel            matlab.ui.control.Label
        bEditField            matlab.ui.control.NumericEditField
        bEditFieldLabel       matlab.ui.control.Label
        aEditField            matlab.ui.control.NumericEditField
        aEditFieldLabel       matlab.ui.control.Label
        GOButton              matlab.ui.control.Button
        DropDown              matlab.ui.control.DropDown
        DropDownLabel         matlab.ui.control.Label
        LoadInputImageButton  matlab.ui.control.Button
        input_blue            matlab.ui.control.UIAxes
        input_green           matlab.ui.control.UIAxes
        input_red             matlab.ui.control.UIAxes
        ImageAxes             matlab.ui.control.UIAxes
        CenterPanel           matlab.ui.container.Panel
        CenterPanel2          matlab.ui.container.Panel
        OutputLabel           matlab.ui.control.Label
        RedAxes_5             matlab.ui.control.UIAxes
        RedAxes_4             matlab.ui.control.UIAxes
        RedAxes_2             matlab.ui.control.UIAxes
        ImageAxes_4           matlab.ui.control.UIAxes
        RightPanel            matlab.ui.container.Panel
        RightPanel2           matlab.ui.container.Panel
        ReferenceLabel        matlab.ui.control.Label
        LoadImageButton       matlab.ui.control.Button
        RedAxes_11            matlab.ui.control.UIAxes
        RedAxes_10            matlab.ui.control.UIAxes
        RedAxes_9             matlab.ui.control.UIAxes
        ImageAxes_5           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Methods that control component visibility
    methods (Access = private) 
        
        function histogramSpecs(app)
            app.CenterPanel.Enable = 'off';
            app.RightPanel.Enable = 'off';

            app.aEditField.Visible ='off';
            app.bEditField.Visible ='off';
            
            app.aEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Visible = 'off';
        end

        function inputSpecs(app, input1, input2)
            app.CenterPanel.Enable = 'on';
            app.RightPanel.Enable = 'off';

            app.aEditField.Visible ='on';
            app.bEditField.Visible ='on';
            
            app.aEditFieldLabel.Visible = 'on';
            app.bEditFieldLabel.Visible = 'on';

            app.aEditFieldLabel.Text = input1;
            app.bEditFieldLabel.Text = input2;
        end

        function noInputSpecs(app)
            app.CenterPanel.Enable = 'on';
            app.RightPanel.Enable = 'off';

            app.aEditField.Visible ='off';
            app.bEditField.Visible ='off';
            
            app.aEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Visible = 'off';
        end

        function histogramSpecSpecs(app)
            app.CenterPanel.Enable = 'on';
            app.RightPanel.Enable = 'on';

            app.aEditField.Visible ='off';
            app.bEditField.Visible ='off';
            
            app.aEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Visible = 'off';
        end
    end
    
    %Show Histogram Method
    methods (Access = public)
        
        function updateImage(app,imagefile)
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure,ME.message,"Image Error");
                    return;            
                end 
            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes_6,im);
                    [~, pixelCount] = show_histogram(im);
                    
                    
                    app.input_red.Visible = "off";
                    app.input_blue.Visible = "off";
                    
                    app.input_green.Title = "Grey Level";

                    bar(app.input_green, pixelCount)
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes_6,im);
                    [~, pixelCount] = show_histogram(im);

                    app.input_red.Visible = "on";
                    app.input_blue.Visible = "on";
                    % Plot the histograms
                    bar(app.input_red,pixelCount(:, 1), 'r')
                    bar(app.input_green,pixelCount(:, 2), 'g')
                    bar(app.input_blue,pixelCount(:, 3), 'b')
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure,"Image must be grayscale or truecolor.","Image Error");
                    return;
                    
            end     
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function startupFcn(app)
            app.CenterPanel.Enable = 'off';
            app.RightPanel.Enable = 'off';

            app.aEditField.Visible ='off';
            app.bEditField.Visible ='off';
            
            app.aEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Visible = 'off';
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case 'Show Histogram'
                    histogramSpecs(app);
                case 'Image Brightening'
                    inputSpecs(app, 'a', 'b')
                case 'Pos to Neg'
                    noInputSpecs(app)
                case 'Neg to Pos'
                    noInputSpecs(app)
                case 'Log Transform'
                    inputSpecs(app, 'c', 'r')
                case 'Power Transform'
                   inputSpecs(app, 'c', 'y')
                case 'Contrast Stretching'
                   noInputSpecs(app)
                case 'Histogram Eq'
                   noInputSpecs(app)
                case 'Histogram Spec'
                    histogramSpecSpecs(app)
                otherwise
                
            end
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {737, 737, 737};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {737, 737};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {385, '1x', 280};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel2 = uipanel(app.LeftPanel);
            app.ImageAxes = uiaxes(app.LeftPanel2);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.input_red = uiaxes(app.LeftPanel2);
            title(app.input_red, 'Red')
            xlabel(app.input_red, 'Grey Level')
            ylabel(app.input_red, 'Frequency')
            app.input_red.XLim = [0 255];
            app.input_red.XTick = [0 128 255];
            app.input_green = uiaxes(app.LeftPanel2);
            title(app.input_green, 'Green')
            xlabel(app.input_green, 'Grey Level')
            ylabel(app.input_green, 'Frequency')
            app.input_green.XLim = [0 255];
            app.input_green.XTick = [0 128 255];
            app.input_blue = uiaxes(app.LeftPanel2);
            title(app.input_blue, 'Blue')
            xlabel(app.input_blue, 'Grey Level')
            ylabel(app.input_blue, 'Frequency')
            app.input_blue.XLim = [0 255];
            app.input_blue.XTick = [0 128 255];
            app.LoadInputImageButton = uibutton(app.LeftPanel2, 'push');
            app.LoadInputImageButton.Text = 'Load Input Image';
            app.DropDownLabel = uilabel(app.LeftPanel2);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.LeftPanel2);
            app.DropDown.Items = {'Show Histogram', 'Image Brightening', 'Pos to Neg', 'Neg to Pos', 'Log Transform', 'Power Transform', 'Contrast Stretching', 'Histogram Eq', 'Histogram Spec'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Placeholder = 'Choose Function';
            app.DropDown.Value = 'Show Histogram';
            app.GOButton = uibutton(app.LeftPanel2, 'push');
            app.GOButton.Text = 'GO!';
            app.aEditFieldLabel = uilabel(app.LeftPanel2);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.LeftPanel2, 'numeric');
            app.bEditFieldLabel = uilabel(app.LeftPanel2);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.LeftPanel2, 'numeric');
            app.InputLabel = uilabel(app.LeftPanel2);
            app.InputLabel.Text = 'Input';
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel2 = uipanel(app.CenterPanel);
            app.ImageAxes_4 = uiaxes(app.CenterPanel2);
            app.ImageAxes_4.XTick = [];
            app.ImageAxes_4.XTickLabel = {'[ ]'};
            app.ImageAxes_4.YTick = [];
            app.RedAxes_2 = uiaxes(app.CenterPanel2);
            title(app.RedAxes_2, 'Red')
            xlabel(app.RedAxes_2, 'Grey Level')
            ylabel(app.RedAxes_2, 'Frequency')
            app.RedAxes_2.XLim = [0 255];
            app.RedAxes_2.XTick = [0 128 255];
            app.RedAxes_4 = uiaxes(app.CenterPanel2);
            title(app.RedAxes_4, 'Red')
            xlabel(app.RedAxes_4, 'Grey Level')
            ylabel(app.RedAxes_4, 'Frequency')
            app.RedAxes_4.XLim = [0 255];
            app.RedAxes_4.XTick = [0 128 255];
            app.RedAxes_5 = uiaxes(app.CenterPanel2);
            title(app.RedAxes_5, 'Red')
            xlabel(app.RedAxes_5, 'Grey Level')
            ylabel(app.RedAxes_5, 'Frequency')
            app.RedAxes_5.XLim = [0 255];
            app.RedAxes_5.XTick = [0 128 255];
            app.OutputLabel = uilabel(app.CenterPanel2);
            app.OutputLabel.Text = 'Output';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel2 = uipanel(app.RightPanel);
            app.ImageAxes_5 = uiaxes(app.RightPanel2);
            app.ImageAxes_5.XTick = [];
            app.ImageAxes_5.XTickLabel = {'[ ]'};
            app.ImageAxes_5.YTick = [];
            app.RedAxes_9 = uiaxes(app.RightPanel2);
            title(app.RedAxes_9, 'Red')
            xlabel(app.RedAxes_9, 'Grey Level')
            ylabel(app.RedAxes_9, 'Frequency')
            app.RedAxes_9.XLim = [0 255];
            app.RedAxes_9.XTick = [0 128 255];
            app.RedAxes_10 = uiaxes(app.RightPanel2);
            title(app.RedAxes_10, 'Red')
            xlabel(app.RedAxes_10, 'Grey Level')
            ylabel(app.RedAxes_10, 'Frequency')
            app.RedAxes_10.XLim = [0 255];
            app.RedAxes_10.XTick = [0 128 255];
            app.RedAxes_11 = uiaxes(app.RightPanel2);
            title(app.RedAxes_11, 'Red')
            xlabel(app.RedAxes_11, 'Grey Level')
            ylabel(app.RedAxes_11, 'Frequency')
            app.RedAxes_11.XLim = [0 255];
            app.RedAxes_11.XTick = [0 128 255];
            app.LoadImageButton = uibutton(app.RightPanel2, 'push');
            app.LoadImageButton.Text = 'Load Image';
            app.ReferenceLabel = uilabel(app.RightPanel2);
            app.ReferenceLabel.Text = 'Reference';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tset

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end